classdef finalGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        OutputsPanel                  matlab.ui.container.Panel
        TabGroup4                     matlab.ui.container.TabGroup
        LogTab                        matlab.ui.container.Tab
        LogTextArea                   matlab.ui.control.TextArea
        LogTextAreaLabel              matlab.ui.control.Label
        ScatterplotsTab               matlab.ui.container.Tab
        TabGroup5                     matlab.ui.container.TabGroup
        NophasenoiseTab_2             matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        PhasenoiseTab                 matlab.ui.container.Tab
        Image2                        matlab.ui.control.Image
        PlotsTab                      matlab.ui.container.Tab
        OutputImagesTab               matlab.ui.container.Tab
        TabGroup6                     matlab.ui.container.TabGroup
        Image1Tab                     matlab.ui.container.Tab
        Image4                        matlab.ui.control.Image
        Image2Tab                     matlab.ui.container.Tab
        Image5                        matlab.ui.control.Image
        RunButton                     matlab.ui.control.Button
        SimulationConfigurationPanel  matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        TransmissionTab               matlab.ui.container.Tab
        HopselectButtonGroup          matlab.ui.container.ButtonGroup
        MultihopButton                matlab.ui.control.RadioButton
        SinglehopButton               matlab.ui.control.RadioButton
        TransmissionMethodLabel       matlab.ui.control.Label
        GroundStationsTab             matlab.ui.container.Tab
        TabGroup3                     matlab.ui.container.TabGroup
        GS1Tab                        matlab.ui.container.Tab
        LocationButtonGroup           matlab.ui.container.ButtonGroup
        SKButton                      matlab.ui.control.RadioButton
        ABButton                      matlab.ui.control.RadioButton
        BCButton                      matlab.ui.control.RadioButton
        UplinkGroundStationLabel      matlab.ui.control.Label
        GS2Tab                        matlab.ui.container.Tab
        LocationButtonGroup_2         matlab.ui.container.ButtonGroup
        QCButton                      matlab.ui.control.RadioButton
        ONButton                      matlab.ui.control.RadioButton
        MBButton                      matlab.ui.control.RadioButton
        DownlinkGroundStationLabel    matlab.ui.control.Label
        ImageTab                      matlab.ui.container.Tab
        Image3                        matlab.ui.control.Image
        InputImageLabel               matlab.ui.control.Label
        ImageDropDown                 matlab.ui.control.DropDown
        ImageDropDownLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        startYear = 2023;
        startMonth = 10
        startDay = 21
        simDuration = 5

        isMultihop = false

        satFreq
        satPower
        satBitRate
        satSysLoss
        
        sat1Periapsis
        sat1Axis
        sat1Inclination
        sat1Ascension
        sat1Eccentricity
        sat1Anomaly

        sat2Periapsis
        sat2Axis
        sat2Inclination
        sat2Ascension
        sat2Eccentricity
        sat2Anomaly

        sat3Periapsis
        sat3Axis
        sat3Inclination
        sat3Ascension
        sat3Eccentricity
        sat3Anomaly

        gs1freq
        gs1power
        gs1bitRate
        gs2lat
        gs2long
    end
    
    methods (Access = public)
        
        function results = runSim(app)
            timeStruct = getTimeCfgStruct(app);
            satCmnStruct = getSatCmnCfgStruct(app);
            sat1Struct = getSatCfgStruct(app, app.sat1Periapsis, app.sat1Axis, app.sat1Inclination, app.sat1Ascension, app.sat1Eccentricity, app.sat1Anomaly);
            sat2Struct = getSatCfgStruct(app, app.sat2Periapsis, app.sat2Axis, app.sat2Inclination, app.sat2Ascension, app.sat2Eccentricity, app.sat2Anomaly);
            sat3Struct = getSatCfgStruct(app, app.sat3Periapsis, app.sat3Axis, app.sat3Inclination, app.sat3Ascension, app.sat3Eccentricity, app.sat3Anomaly);
            gsStruct = getGsCfg(app);


            provinceIndex1 = -1;
            provinceIndex2 = -1;
            % provinceIndex1, provinceIndex2 = setProvinces(app);
            if app.BCButton.Value
                provinceIndex1 = 1;
            elseif app.ABButton.Value
                provinceIndex1 = 2;
            elseif app.SKButton.Value
                provinceIndex1 = 3;
            end

            if app.MBButton.Value
                provinceIndex2 = 1;
            elseif app.ONButton.Value
                provinceIndex2 = 2;
            elseif app.QCButton.Value
                provinceIndex2 = 3;
            end

            imageChoice = app.ImageDropDown.ValueIndex;
            plotFigure = 0;
            outputImage1 = -1;
            outputImage2 = -1;
            outString = "";

            clearVisuals(app);

            if app.SinglehopButton.Value

                mainCenter;

                % Define the path to your PNG image (replace with your actual path)
                image1Path = fullfile('figure1.png');
                image2Path = fullfile('figure2.png');
            
                % Read the image using imread
                image = imread(image1Path);
                image2 = imread(image2Path);
              
                % Get a handle to the UIImage component (assuming it's named 'myImage')
                myImage = app.Image;
                my2Image = app.Image2;
              
                % Set the ImageSource property of UIImage to display the image
                myImage.ImageSource = image;
                my2Image.ImageSource = image2;
            else
                mainCenter2;
            end
            app.LogTextArea.Value = outString;
            
            % Copy plot elements to the uiaxes
            h = copyobj(plotFigure.Children, app.PlotsTab);  % Copy all plot elements
            set(h, 'Parent', app.PlotsTab);  % Set parent to the uiaxes
            close(plotFigure);  % Close the invisible figure

            if outputImage1 ~= -1
                % Define the path to your PNG image (replace with your actual path)
                image1Path = fullfile('./out/sat1Img.jpg');
            
                % Read the image using imread
                image = imread(image1Path);
              
                % Get a handle to the UIImage component (assuming it's named 'myImage')
                myImage = app.Image4;
                % Set the ImageSource property of UIImage to display the image
                myImage.ImageSource = image;
            end

            if outputImage2 ~= -1
                % Define the path to your PNG image (replace with your actual path)
                image1Path = fullfile('./out/sat2Img.jpg');
            
                % Read the image using imread
                image = imread(image1Path);
              
                % Get a handle to the UIImage component (assuming it's named 'myImage')
                myImage = app.Image5;
                % Set the ImageSource property of UIImage to display the image
                myImage.ImageSource = image;
                % app.Image5.ImageSource = outputImage2;
                % Copy plot elements to the uiaxes
                % h = copyobj(outputImage2.Children, app.Image2Tab);  % Copy all plot elements
                % set(h, 'Parent', app.Image2Tab);  % Set parent to the uiaxes
            end

            close all;
            
        end
        
        function attrStruct = getTimeCfgStruct(app)
            attrStruct = struct;
            attrStruct.startYear = app.startYear;
            attrStruct.startMonth = app.startMonth;
            attrStruct.startDay = app.startDay;
            attrStruct.simDuration = app.simDuration;
        end


        function results = updateAttr(app)
            app.startYear = app.YearEditField.Value;
            app.startMonth = app.MonthEditField.Value;
            app.startDay = app.DayEditField.Value;
            app.simDuration = app.DurationhoursEditField.Value;

            app.satFreq = app.SystemLossEditField.Value;
            app.satBitRate = app.BitRateEditField_2.Value;
            app.satPower = app.PowerEditField.Value;
            app.satSysLoss = app.SystemLossEditField.Value;
            
            app.sat1Periapsis= app.ArgofPeriapsisEditField.Value;
            app.sat1Ascension= app.RightAscensionEditField.Value;
            app.sat1Inclination= app.InclinationEditField.Value;
            app.sat1Eccentricity= app.EccentricityEditField.Value;
            app.sat1Axis= app.SemiMajorAxisEditField.Value;
            app.sat1Anomaly = app.TrueAnomalyEditField.Value;

            app.sat2Periapsis= app.ArgofPeriapsisEditField_2.Value;
            app.sat2Ascension= app.RightAscensionEditField_2.Value;
            app.sat2Inclination= app.InclinationEditField_2.Value;
            app.sat2Eccentricity= app.EccentricityEditField_2.Value;
            app.sat2Axis= app.SemiMajorAxisEditField_2.Value;
            app.sat2Anomaly = app.TrueAnomalyEditField_2.Value;

            app.sat3Periapsis= app.ArgofPeriapsisEditField_3.Value;
            app.sat3Ascension= app.RightAscensionEditField_3.Value;
            app.sat3Inclination= app.InclinationEditField_3.Value;
            app.sat3Eccentricity= app.EccentricityEditField_3.Value;
            app.sat3Axis= app.SemiMajorAxisEditField_3.Value;
            app.sat3Anomaly = app.TrueAnomalyEditField_3.Value;
            
            app.gs2lat = app.LatitudeEditField_2.Value;
            app.gs2long = app.LongitudeEditField_2.Value;

            app.gs1freq = app.FrequencyEditField_2.Value;
            app.gs1power = app.PowerEditField_2.Value;
            app.gs1bitRate = app.BitRateEditField_3.Value;

        end
        
        function results = getSatCfgStruct(app, periapsis, axis, inc, asc, ecc, ano)
            results = struct;
            results.periapsis = periapsis;
            results.axis = axis;
            results.inc = inc;
            results.asc = asc;
            results.ecc = ecc;
            results.ano = ano;
        end
        
        function results = getSatCmnCfgStruct(app)
            results = struct;
            results.freq = app.satFreq;
            results.power = app.satPower;
            results.bitRate = app.satBitRate;
            results.sysLoss = app.satSysLoss;
        end
        
        function results = getGsCfg(app)
            results = struct;
            results.gs2Lat = app.gs2lat;
            results.gs2Long = app.gs2long;
            results.gs1Freq = app.gs1freq;
            results.gs1Power = app.gs1power;
            results.gs1BitRate = app.gs1bitRate;
        end
        
        function [gs1Province, gs2Province] = setProvinces(app)
            if app.BCButton.Value
                gs1Province = 1;
            elseif app.ABButton.Value
                gs1Province = 2;
            else
                gs1Province = 3;
            end

            if app.MBButton.Value
                gs2Province = 1;
            elseif app.ONButton.Value
                gs2Province = 2;
            else
                gs2Province = 3;
            end

        end
        
        function results = clearVisuals(app)
            app.LogTextArea.Value = "Loading...";
            app.Image4.ImageSource = zeros(0, 0, 3);
            app.Image5.ImageSource = zeros(0, 0, 3);
            childrenToDelete = get(app.PlotsTab, 'Children');

            % Delete all child objects
            delete(childrenToDelete);
            drawnow;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %runSim(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % updateAttr(app);
            runSim(app);
        end

        % Callback function
        function YearEditFieldValueChanged(app, event)
            value = app.YearEditField.Value;
            app.startYear = value;
        end

        % Callback function
        function MonthEditFieldValueChanged(app, event)
            value = app.MonthEditField.Value;
            app.startMonth = value;
        end

        % Callback function
        function DayEditFieldValueChanged(app, event)
            value = app.DayEditField.Value;
            app.startDay = value;
        end

        % Callback function
        function DurationhoursEditFieldValueChanged(app, event)
            value = app.DurationhoursEditField.Value;
            app.simDuration = value;
        end

        % Callback function
        function MultihopCheckBoxValueChanged(app, event)
            value = app.MultihopCheckBox.Value;
            app.isMultihop = value;
        end

        % Value changed function: ImageDropDown
        function ImageDropDownValueChanged(app, event)
            value = app.ImageDropDown.Value;

            % Define the path to your PNG image (replace with your actual path)
            image3Path = fullfile("Images/"+value);

            if value == "Webcam"
                image3Path = fullfile("Images/webcamIcon.png");
            end
        
            % Read the image using imread
            image = imread(image3Path);
          
            % Get a handle to the UIImage component (assuming it's named 'myImage')
            myImage = app.Image3;
          
            % Set the ImageSource property of UIImage to display the image
            myImage.ImageSource = image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationConfigurationPanel = uipanel(app.UIFigure);
            app.SimulationConfigurationPanel.Title = 'Simulation Configuration';
            app.TabGroup = uitabgroup(app.SimulationConfigurationPanel);
            app.TransmissionTab = uitab(app.TabGroup);
            app.TransmissionTab.Title = 'Transmission';
            app.TransmissionMethodLabel = uilabel(app.TransmissionTab);
            app.TransmissionMethodLabel.Text = 'Transmission Method';
            app.HopselectButtonGroup = uibuttongroup(app.TransmissionTab);
            app.HopselectButtonGroup.Title = 'Hop select';
            app.SinglehopButton = uiradiobutton(app.HopselectButtonGroup);
            app.SinglehopButton.Text = 'Single-hop';
            app.SinglehopButton.Value = true;
            app.MultihopButton = uiradiobutton(app.HopselectButtonGroup);
            app.MultihopButton.Text = 'Multi-hop';
            app.GroundStationsTab = uitab(app.TabGroup);
            app.GroundStationsTab.Title = 'Ground Stations';
            app.TabGroup3 = uitabgroup(app.GroundStationsTab);
            app.GS1Tab = uitab(app.TabGroup3);
            app.GS1Tab.Title = 'GS 1';
            app.UplinkGroundStationLabel = uilabel(app.GS1Tab);
            app.UplinkGroundStationLabel.Text = 'Uplink Ground Station';
            app.LocationButtonGroup = uibuttongroup(app.GS1Tab);
            app.LocationButtonGroup.Title = 'Location';
            app.BCButton = uiradiobutton(app.LocationButtonGroup);
            app.BCButton.Text = 'BC';
            app.BCButton.Value = true;
            app.ABButton = uiradiobutton(app.LocationButtonGroup);
            app.ABButton.Text = 'AB';
            app.SKButton = uiradiobutton(app.LocationButtonGroup);
            app.SKButton.Text = 'SK';
            app.GS2Tab = uitab(app.TabGroup3);
            app.GS2Tab.Title = 'GS 2';
            app.DownlinkGroundStationLabel = uilabel(app.GS2Tab);
            app.DownlinkGroundStationLabel.Text = 'Downlink Ground Station';
            app.LocationButtonGroup_2 = uibuttongroup(app.GS2Tab);
            app.LocationButtonGroup_2.Title = 'Location';
            app.MBButton = uiradiobutton(app.LocationButtonGroup_2);
            app.MBButton.Text = 'MB';
            app.ONButton = uiradiobutton(app.LocationButtonGroup_2);
            app.ONButton.Text = 'ON';
            app.ONButton.Value = true;
            app.QCButton = uiradiobutton(app.LocationButtonGroup_2);
            app.QCButton.Text = 'QC';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ImageDropDownLabel = uilabel(app.ImageTab);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.ImageTab);
            app.ImageDropDown.Items = {'Flower1.png', 'Flower2.jpeg', 'Mountain.jpeg', 'Color_Wheel.jpg', 'Landscape.jpg', 'Denmark.jpeg', 'Village.jpg', 'Mickey.jpg', 'Shrine.jpg', 'Webcam'};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged, true);
            app.ImageDropDown.Value = 'Flower1.png';
            app.InputImageLabel = uilabel(app.ImageTab);
            app.InputImageLabel.Text = 'Input Image';
            app.Image3 = uiimage(app.ImageTab);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.TabGroup4 = uitabgroup(app.OutputsPanel);
            app.LogTab = uitab(app.TabGroup4);
            app.LogTab.Title = 'Log';
            app.LogTextAreaLabel = uilabel(app.LogTab);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.LogTab);
            app.ScatterplotsTab = uitab(app.TabGroup4);
            app.ScatterplotsTab.Title = 'Scatter plots';
            app.TabGroup5 = uitabgroup(app.ScatterplotsTab);
            app.NophasenoiseTab_2 = uitab(app.TabGroup5);
            app.NophasenoiseTab_2.Title = 'No phase noise';
            app.Image = uiimage(app.NophasenoiseTab_2);
            app.PhasenoiseTab = uitab(app.TabGroup5);
            app.PhasenoiseTab.Title = 'Phase noise';
            app.Image2 = uiimage(app.PhasenoiseTab);
            app.PlotsTab = uitab(app.TabGroup5);
            app.PlotsTab.Title = 'Plots';
            app.OutputImagesTab = uitab(app.TabGroup4);
            app.OutputImagesTab.Title = 'Output Images';
            app.TabGroup6 = uitabgroup(app.OutputImagesTab);
            app.Image1Tab = uitab(app.TabGroup6);
            app.Image1Tab.Title = 'Image 1';
            app.Image4 = uiimage(app.Image1Tab);
            app.Image2Tab = uitab(app.TabGroup6);
            app.Image2Tab.Title = 'Image 2';
            app.Image5 = uiimage(app.Image2Tab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end