classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        RunButton                    matlab.ui.control.Button
        DurationhoursEditField       matlab.ui.control.NumericEditField
        DurationhoursEditFieldLabel  matlab.ui.control.Label
        DayEditField                 matlab.ui.control.NumericEditField
        DayEditFieldLabel            matlab.ui.control.Label
        MonthEditField               matlab.ui.control.NumericEditField
        MonthEditFieldLabel          matlab.ui.control.Label
        SimulationTimeConfigurationLabel  matlab.ui.control.Label
        YearEditField                matlab.ui.control.NumericEditField
        YearEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        startYear = 2023;
        startMonth = 10
        startDay = 21
        simDuration = 5
    end
    
    methods (Access = public)
        
        function results = runSim(app)
            argStruct = mapAttrToStruct(app);
            testingSat(argStruct);
        end
        
        function attrStruct = mapAttrToStruct(app)
            attrStruct = struct;
            attrStruct.startYear = app.startYear;
            attrStruct.startMonth = app.startMonth;
            attrStruct.startDay = app.startDay;
            attrStruct.simDuration = app.simDuration;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %runSim(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            runSim(app);
        end

        % Value changed function: YearEditField
        function YearEditFieldValueChanged(app, event)
            value = app.YearEditField.Value;
            app.startYear = value;
        end

        % Value changed function: MonthEditField
        function MonthEditFieldValueChanged(app, event)
            value = app.MonthEditField.Value;
            app.startMonth = value;
        end

        % Value changed function: DayEditField
        function DayEditFieldValueChanged(app, event)
            value = app.DayEditField.Value;
            app.startDay = value;
        end

        % Value changed function: DurationhoursEditField
        function DurationhoursEditFieldValueChanged(app, event)
            value = app.DurationhoursEditField.Value;
            app.simDuration = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.YearEditFieldLabel = uilabel(app.Panel);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.Panel, 'numeric');
            app.YearEditField.ValueChangedFcn = createCallbackFcn(app, @YearEditFieldValueChanged, true);
            app.YearEditField.Value = 2023;
            app.SimulationTimeConfigurationLabel = uilabel(app.Panel);
            app.SimulationTimeConfigurationLabel.Text = 'Simulation Time Configuration';
            app.MonthEditFieldLabel = uilabel(app.Panel);
            app.MonthEditFieldLabel.Text = 'Month';
            app.MonthEditField = uieditfield(app.Panel, 'numeric');
            app.MonthEditField.ValueChangedFcn = createCallbackFcn(app, @MonthEditFieldValueChanged, true);
            app.MonthEditField.Value = 10;
            app.DayEditFieldLabel = uilabel(app.Panel);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.Panel, 'numeric');
            app.DayEditField.ValueChangedFcn = createCallbackFcn(app, @DayEditFieldValueChanged, true);
            app.DayEditField.Value = 21;
            app.DurationhoursEditFieldLabel = uilabel(app.Panel);
            app.DurationhoursEditFieldLabel.Text = 'Duration (hours)';
            app.DurationhoursEditField = uieditfield(app.Panel, 'numeric');
            app.DurationhoursEditField.ValueChangedFcn = createCallbackFcn(app, @DurationhoursEditFieldValueChanged, true);
            app.DurationhoursEditField.Value = 5;
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end